classdef ModeloBiosistemas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        RightPanel           matlab.ui.container.Panel
        Image                matlab.ui.control.Image
        SimularButton        matlab.ui.control.Button
        UITable              matlab.ui.control.Table
        UITable_2            matlab.ui.control.Table
        CenterPanel          matlab.ui.container.Panel
        TiempoSlider         matlab.ui.control.Slider
        TiempoSliderLabel    matlab.ui.control.Label
        JPinaVMartinezDLopezJPradaLabel  matlab.ui.control.Label
        CargarArchivoButton  matlab.ui.control.Button
        PathEditField        matlab.ui.control.EditField
        PathEditFieldLabel   matlab.ui.control.Label
        AnlisisdeterministicoporbalancedemateriaModelodeSigma32Label  matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarArchivoButton
        function CargarArchivoButtonPushed(app, event)
            [filename, path] = uigetfile('*.xlsx');
            
            if(ischar(filename))
                app.PathEditField.Value = strcat(path,filename);
                t1 = readtable(strcat(path,filename),"sheet","conInic");
                t2 = readtable(strcat(path,filename),"sheet","react");
                
                app.UITable.Data = t1;
                app.UITable_2.Data = t2;
                figure(app.UIFigure)
                
            end
        end

        % Value changed function: PathEditField
        function PathEditFieldValueChanged(app, event)
            value = app.PathEditField.Value;
            
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            % Acá va el modelo que se requiere simular
            global k
            global k sig32gene sig32mrna;
            global sig32 ftshgene ftsh;
            global jcomp_gene jcomp jcomp_sig32 e_sig32;

            %initialConditions=[6; 9; 1; 56; 93; 7];
            Conditions = table2array(app.UITable.Data(:,{'Concinic'}));
            
            %k = [1.4e-5, 1.4e-6, 0.7, 0.7, 0.13, 4.41e-3, 7.40e-11, 4.41e-3, 6.40e-10, 3.27e-4, 4.4e-4, 1.28e-9];
            k = table2array(app.UITable_2.Data(:,{'reactions'}));
            
            namesC = table2array(app.UITable.Data(:,{'Especie'}));

            
            sig32gene=Conditions(1);
            sig32mrna=Conditions(2);
            sig32=Conditions(3);
            ftshgene=Conditions(4);
            ftsh=Conditions(5);
            jcomp_gene=Conditions(6);
            jcomp=Conditions(7);
            jcomp_sig32=Conditions(8);
            e_sig32=Conditions(9);

            initialConditions = [sig32mrna,sig32,e_sig32,jcomp,ftsh,jcomp_sig32];
            nameEspe = [string(cell2mat(namesC(2))),string(cell2mat(namesC(3))),string(cell2mat(namesC(9))), ...
                        string(cell2mat(namesC(7))),string(cell2mat(namesC(5))),string(cell2mat(namesC(8)))];
            
            tf = app.TiempoSlider.Value;
            %disp(tf);
            t=[0 tf];
            
            [T,Y]=ode45(@sigmaModel, t, initialConditions);
            
            [fil,col] = size(Y);
            %disp(size(Y))
            for i=1:5:fil-1
                hold(app.UIAxes, 'on');
                grid(app.UIAxes,"on");
                plot(app.UIAxes,T(1:i),Y(1:i,1),'-r');
                plot(app.UIAxes,T(1:i),Y(1:i,2),'.b');
                plot(app.UIAxes,T(1:i),Y(1:i,3),'-.r');
                plot(app.UIAxes,T(1:i),Y(1:i,4),'--k');
                plot(app.UIAxes,T(1:i),Y(1:i,5),'-<b','MarkerSize',2);
                plot(app.UIAxes,T(1:i),Y(1:i,6),'-k');
                xlim(app.UIAxes,[min(T),max(T)])
                ylim(app.UIAxes,[min(min(Y(:,1:3))),max(max(Y(:,1:3)))])
                xlabel(app.UIAxes,"Tiempo");ylabel(app.UIAxes,"Concentración")
                title(app.UIAxes,"Modelo de la Sigma32");
                legend(app.UIAxes,nameEspe);
                pause(0.004);
            end
            hold(app.UIAxes, 'off')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CenterPanel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AnlisisdeterministicoporbalancedemateriaModelodeSigma32Label = uilabel(app.CenterPanel);
            app.AnlisisdeterministicoporbalancedemateriaModelodeSigma32Label.Text = {'Análisis deterministico  '; 'por balance de materia'; 'Modelo de Sigma32'};
            app.PathEditFieldLabel = uilabel(app.CenterPanel);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.CenterPanel, 'text');
            app.PathEditField.ValueChangedFcn = createCallbackFcn(app, @PathEditFieldValueChanged, true);
            app.CargarArchivoButton = uibutton(app.CenterPanel, 'push');
            app.CargarArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @CargarArchivoButtonPushed, true);
            app.CargarArchivoButton.Text = 'Cargar Archivo';
            app.JPinaVMartinezDLopezJPradaLabel = uilabel(app.CenterPanel);
            app.JPinaVMartinezDLopezJPradaLabel.Text = 'J.Pina, V.Martinez, D.Lopez, J.Prada';
            app.TiempoSliderLabel = uilabel(app.CenterPanel);
            app.TiempoSliderLabel.Text = 'Tiempo';
            app.TiempoSlider = uislider(app.CenterPanel);
            app.TiempoSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.TiempoSlider.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.TiempoSlider.Value = 60;
            app.RightPanel = uipanel(app.UIFigure);
            app.UITable_2 = uitable(app.RightPanel);
            app.UITable_2.ColumnName = {'Constante'; 'Valor'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = true;
            app.UITable_2.Tag = 'reactions';
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Especie'; 'Concentración Inicial.'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.SimularButton = uibutton(app.RightPanel, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'figures', 'pixlr-bg-result.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModeloBiosistemas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end